classdef compare_expected_file_names_to_existing_files < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectFolderButton              matlab.ui.control.Button
        ImageLoadLocationEditFieldLabel  matlab.ui.control.Label
        ImageLoadLocationEditField      matlab.ui.control.EditField
        SelectFileButton                matlab.ui.control.Button
        FileNamesLoadLocationEditFieldLabel  matlab.ui.control.Label
        FileNamesLoadLocationEditField  matlab.ui.control.EditField
        SelectFolderButton_2            matlab.ui.control.Button
        OutputFileSaveLocationEditFieldLabel  matlab.ui.control.Label
        OutputFileSaveLocationEditField  matlab.ui.control.EditField
        AnalyseButton                   matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        dirname %variable names to pass between buttons, this is load directory for images
        strfile %This is the excel file to read
        fa %This is the excel file's parent directory
        fb %This is the name of the excel file
        dirname2 %This is save directory for images
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dirname=uigetdir;
            app.ImageLoadLocationEditField.Value=dirname;
            app.dirname=dirname;
            cd(dirname)
            figure(app.UIFigure) %This cheeky line solves the problems of the 
            % UI being sent to the background 
            %(Below all other open windows) instead of being returned too
            %You can see it functioning when the UI flickers mildly on
            %folder selection.
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
             [fa,fb]=uigetfile('*');
            strfile=strcat(fb,fa);
            app.FileNamesLoadLocationEditField.Value=strfile;
            app.strfile=strcat(fb,fa);
            app.fa=fa;
            app.fb=fb;
            figure(app.UIFigure)
        end

        % Button pushed function: SelectFolderButton_2
        function SelectFolderButton_2Pushed(app, event)
            dirname2=uigetdir;
            app.OutputFileSaveLocationEditField.Value=dirname2;
            dirname2=strcat(dirname2,'\');
            app.dirname2=dirname2;
            figure(app.UIFigure)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
             closereq()
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
        %Author: Matthew Hughes
%Status: Functional
%_________________________________
%Load and save directories
%_________________________________
dirname=app.dirname;
dir2=app.strfile;
SavDir=app.dirname2;
somename='DifferencesInImagesAndNames.xlsx'; 
%_________________________________
%Now the actual functionality
%_________________________________
files=dir(app.dirname);
files={files.name}';
files=files(3:end); 
files=reverse(files);
dotlocation=strfind(files,'.'); %This section figures out the file extensions
for i=1:length(dotlocation) %Some of our files have multiple dots from things such as v1.2.
DotLocation(i)=dotlocation{i,1}(1,1); %So this section is a workaround for those
end
DotLocation=DotLocation';
files=reverse(files);
for j=1:length(files)
FNameIm=files{j,1}(1:end-DotLocation(j));
FNameImg{j,1}=FNameIm;
end
data=importdata(dir2);
FNameXls=data(:,1); %Get the file names we should have
forbidden=' ';
for k=1:length(FNameImg)
    clear Stored
    Stored=FNameImg{k,1};
    FNameImg{k,1}=erase(Stored,forbidden);
end
for j=1:length(FNameXls)
    clear Stored
    Stored=FNameXls{j,1};
    FNameXls{j,1}=erase(Stored,forbidden);
end
FNameImg=strtrim(FNameImg);
FNameXls=strtrim(FNameXls);
UniqueImage=setdiff(FNameImg,FNameXls);
UniqueFileNames=setdiff(FNameXls,FNameImg);
[m,n]=size(UniqueFileNames);
[q,r]=size(UniqueImage);
if m==0 %Get around problems inherrent in empty sets of missing entries
UniqueFileNames{1,1}='No Missing Entries';
end
if q==0
UniqueImage{1,1}='No Missing Entries';
end
SaveDirectory=strcat(app.dirname2,'\',somename);
%Here we create the output Excel document
writematrix('Unmatched Image Names',SaveDirectory,'Sheet',1,'Range','A1');
writematrix('Unmatched File Names from Excel Document',SaveDirectory,'Sheet',1,'Range','B1');
writecell(UniqueImage,SaveDirectory,'Sheet',1,'Range','A2');
writecell(UniqueFileNames,SaveDirectory,'Sheet',1,'Range','B2');
f = msgbox('Operation Completed','Success'); %This one is for the pop-up at the 
%end so we know the process is complete and successful
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ImageLoadLocationEditFieldLabel = uilabel(app.UIFigure);
            app.ImageLoadLocationEditFieldLabel.Text = 'Image Load Location';
            app.ImageLoadLocationEditField = uieditfield(app.UIFigure, 'text');
            app.ImageLoadLocationEditField.Value = '--Image Location--';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FileNamesLoadLocationEditFieldLabel = uilabel(app.UIFigure);
            app.FileNamesLoadLocationEditFieldLabel.Text = 'File Names Load Location';
            app.FileNamesLoadLocationEditField = uieditfield(app.UIFigure, 'text');
            app.FileNamesLoadLocationEditField.Value = '--Excel Document with a column of names--';
            app.SelectFolderButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_2Pushed, true);
            app.SelectFolderButton_2.Text = 'Select Folder';
            app.OutputFileSaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFileSaveLocationEditFieldLabel.Text = 'Output File Save Location';
            app.OutputFileSaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFileSaveLocationEditField.Value = '--Output Folder--';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compare_expected_file_names_to_existing_files

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end